classdef InputPrompt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        NonlinearButton                 matlab.ui.control.StateButton
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MultiButton                     matlab.ui.control.ToggleButton
        PureButton                      matlab.ui.control.ToggleButton
        SensitiveButton                 matlab.ui.control.StateButton
        CortifluidButton                matlab.ui.control.StateButton
        IOdirectoryPanel                matlab.ui.container.Panel
        OuputdirectoryEditField         matlab.ui.control.EditField
        OuputdirectoryLabel             matlab.ui.control.Label
        InputdirectoryEditField         matlab.ui.control.EditField
        InputdirectoryLabel             matlab.ui.control.Label
        InputvaluesPanel                matlab.ui.container.Panel
        PermeabilityAlphaBetaEditField  matlab.ui.control.EditField
        PermeabilityAlphaBetaEditFieldLabel  matlab.ui.control.Label
        StimpressurelevelindBSPLEditField  matlab.ui.control.EditField
        StimpressurelevelindBSPLEditFieldLabel  matlab.ui.control.Label
        Numoffrequencies0onlyinputEditField  matlab.ui.control.EditField
        Numoffrequencies0onlyinputEditFieldLabel  matlab.ui.control.Label
        Stimfreqrangef1f2inkHzEditField  matlab.ui.control.EditField
        Stimfreqrangef1f2inkHzEditFieldLabel  matlab.ui.control.Label
        LofmodeledpieceinmmEditField    matlab.ui.control.EditField
        LofmodeledpieceinmmEditFieldLabel  matlab.ui.control.Label
        CenterlocationinmmEditField     matlab.ui.control.EditField
        CenterlocationinmmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        defaultanswer       
    end
    
    properties (Access = public)
        z
        output
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, z)
            app.defaultanswer={'./hinput/','./houtput/','6','12',...
                '[0.3,30]','21','80','[1e-2,1e-4]','0','1','0','1'};
            if ~exist('z','var')
                z = app.defaultanswer;
            end
            app.InputdirectoryEditField.Value = z{1};
            app.OuputdirectoryEditField.Value = z{2};
            app.CenterlocationinmmEditField.Value = z{3};
            app.LofmodeledpieceinmmEditField.Value = z{4};
            app.Stimfreqrangef1f2inkHzEditField.Value = z{5};
            app.Numoffrequencies0onlyinputEditField.Value = z{6};
            app.StimpressurelevelindBSPLEditField.Value = z{7};
            app.PermeabilityAlphaBetaEditField.Value = z{8};
            if str2num(z{9}) == 1
                app.MultiButton.Value = true;
            else
                app.PureButton.Value = true;
            end

            app.CortifluidButton.Value = false;
            app.SensitiveButton.Value = false;
            if str2num(z{10}) == 1, app.CortifluidButton.Value = true; end
            if str2num(z{11}) == 1, app.SensitiveButton.Value = true; end        
            if str2num(z{12}) == 1, app.SensitiveButton.Value = true; end
            app.z = z;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.output = app.z;
        end

        % Value changed function: OuputdirectoryEditField
        function OuputdirectoryEditFieldValueChanged(app, event)
            value = app.OuputdirectoryEditField.Value;
            app.z{2} = value;
        end

        % Value changed function: InputdirectoryEditField
        function InputdirectoryEditFieldValueChanged(app, event)
            value = app.InputdirectoryEditField.Value;
            app.z{1} = value;
        end

        % Value changed function: PermeabilityAlphaBetaEditField
        function PermeabilityAlphaBetaEditFieldValueChanged(app, event)
            value = app.PermeabilityAlphaBetaEditField.Value;
            app.z{8} = value;
        end

        % Value changed function: StimpressurelevelindBSPLEditField
        function StimpressurelevelindBSPLEditFieldValueChanged(app, event)
            value = app.StimpressurelevelindBSPLEditField.Value;
            app.z{7} = value;
        end

        % Value changed function: Numoffrequencies0onlyinputEditField
        function Numoffrequencies0onlyinputEditFieldValueChanged(app, event)
            value = app.Numoffrequencies0onlyinputEditField.Value;
            app.z{6} = value;
        end

        % Value changed function: Stimfreqrangef1f2inkHzEditField
        function Stimfreqrangef1f2inkHzEditFieldValueChanged(app, event)
            value = app.Stimfreqrangef1f2inkHzEditField.Value;
            app.z{5} = value;
        end

        % Value changed function: LofmodeledpieceinmmEditField
        function LofmodeledpieceinmmEditFieldValueChanged(app, event)
            value = app.LofmodeledpieceinmmEditField.Value;
            app.z{4} = value;
        end

        % Value changed function: CenterlocationinmmEditField
        function CenterlocationinmmEditFieldValueChanged(app, event)
            value = app.CenterlocationinmmEditField.Value;
            app.z{3} = value;
        end

        % Value changed function: SensitiveButton
        function SensitiveButtonValueChanged(app, event)
            value = app.SensitiveButton.Value;
            if value
                app.z{11} = '1';
            else
                app.z{11} = '0';
            end
        end

        % Value changed function: CortifluidButton
        function CortifluidButtonValueChanged(app, event)
            value = app.CortifluidButton.Value;
            if value
                app.z{10} = '1';
            else
                app.z{10} = '0';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'multi')
                app.z{9} = '1';
            else
                app.z{9} = '0';
            end
        end

        % Value changed function: NonlinearButton
        function NonlinearButtonValueChanged(app, event)
            value = app.NonlinearButton.Value;
            if value
                app.z{12} = '1';
            else
                app.z{12} = '0';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputvaluesPanel = uipanel(app.UIFigure);
            app.InputvaluesPanel.Title = 'Input values';
            app.CenterlocationinmmEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.CenterlocationinmmEditFieldLabel.Text = 'Center location in [mm]';
            app.CenterlocationinmmEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.CenterlocationinmmEditField.ValueChangedFcn = createCallbackFcn(app, @CenterlocationinmmEditFieldValueChanged, true);
            app.LofmodeledpieceinmmEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.LofmodeledpieceinmmEditFieldLabel.Text = 'L of modeled piece in [mm]';
            app.LofmodeledpieceinmmEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.LofmodeledpieceinmmEditField.ValueChangedFcn = createCallbackFcn(app, @LofmodeledpieceinmmEditFieldValueChanged, true);
            app.Stimfreqrangef1f2inkHzEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.Stimfreqrangef1f2inkHzEditFieldLabel.Text = 'Stim freq range [f1, f2] in [kHz]';
            app.Stimfreqrangef1f2inkHzEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.Stimfreqrangef1f2inkHzEditField.ValueChangedFcn = createCallbackFcn(app, @Stimfreqrangef1f2inkHzEditFieldValueChanged, true);
            app.Numoffrequencies0onlyinputEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.Numoffrequencies0onlyinputEditFieldLabel.Text = 'Num of frequencies [0: only input]';
            app.Numoffrequencies0onlyinputEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.Numoffrequencies0onlyinputEditField.ValueChangedFcn = createCallbackFcn(app, @Numoffrequencies0onlyinputEditFieldValueChanged, true);
            app.StimpressurelevelindBSPLEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.StimpressurelevelindBSPLEditFieldLabel.Text = 'Stim pressure level in [dB SPL]';
            app.StimpressurelevelindBSPLEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.StimpressurelevelindBSPLEditField.ValueChangedFcn = createCallbackFcn(app, @StimpressurelevelindBSPLEditFieldValueChanged, true);
            app.PermeabilityAlphaBetaEditFieldLabel = uilabel(app.InputvaluesPanel);
            app.PermeabilityAlphaBetaEditFieldLabel.Text = 'Permeability [Alpha, Beta]';
            app.PermeabilityAlphaBetaEditField = uieditfield(app.InputvaluesPanel, 'text');
            app.PermeabilityAlphaBetaEditField.ValueChangedFcn = createCallbackFcn(app, @PermeabilityAlphaBetaEditFieldValueChanged, true);
            app.IOdirectoryPanel = uipanel(app.UIFigure);
            app.IOdirectoryPanel.Title = 'I/O directory';
            app.InputdirectoryLabel = uilabel(app.IOdirectoryPanel);
            app.InputdirectoryLabel.Text = 'Input directory';
            app.InputdirectoryEditField = uieditfield(app.IOdirectoryPanel, 'text');
            app.InputdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @InputdirectoryEditFieldValueChanged, true);
            app.OuputdirectoryLabel = uilabel(app.IOdirectoryPanel);
            app.OuputdirectoryLabel.Text = 'Ouput directory';
            app.OuputdirectoryEditField = uieditfield(app.IOdirectoryPanel, 'text');
            app.OuputdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OuputdirectoryEditFieldValueChanged, true);
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.CortifluidButton = uibutton(app.SettingsPanel, 'state');
            app.CortifluidButton.ValueChangedFcn = createCallbackFcn(app, @CortifluidButtonValueChanged, true);
            app.CortifluidButton.Text = 'Corti fluid';
            app.SensitiveButton = uibutton(app.SettingsPanel, 'state');
            app.SensitiveButton.ValueChangedFcn = createCallbackFcn(app, @SensitiveButtonValueChanged, true);
            app.SensitiveButton.Text = 'Sensitive';
            app.ButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PureButton = uitogglebutton(app.ButtonGroup);
            app.PureButton.Text = 'Pure';
            app.PureButton.Value = true;
            app.MultiButton = uitogglebutton(app.ButtonGroup);
            app.MultiButton.Text = 'Multi';
            app.NonlinearButton = uibutton(app.SettingsPanel, 'state');
            app.NonlinearButton.ValueChangedFcn = createCallbackFcn(app, @NonlinearButtonValueChanged, true);
            app.NonlinearButton.Text = 'Nonlinear';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputPrompt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end