classdef post_NLFD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotFluidPressureButton      matlab.ui.control.Button
        showMovie                    matlab.ui.control.Switch
        showForce                    matlab.ui.control.Switch
        PlotAmpTuningButton          matlab.ui.control.Button
        PlotCortifluidButton         matlab.ui.control.Button
        PlotPermeationButton         matlab.ui.control.Button
        PlotAreaChangeButton         matlab.ui.control.Button
        PlotxHBpoButton              matlab.ui.control.Button
        PlotTravelingwaveButton      matlab.ui.control.Button
        StimulationLabel             matlab.ui.control.Label
        ActiveButton                 matlab.ui.control.StateButton
        PassiveButton                matlab.ui.control.StateButton
        SpanEditField                matlab.ui.control.NumericEditField
        SpanEditFieldLabel           matlab.ui.control.Label
        DemoscalafluidButton         matlab.ui.control.Button
        ScalingfactorEditField       matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel  matlab.ui.control.Label
        DemocrosssectionButton       matlab.ui.control.Button
        Prompt_message               matlab.ui.control.Label
        FreqkHzDropDown              matlab.ui.control.DropDown
        FreqkHzDropDownLabel         matlab.ui.control.Label
        PlotOoCGainButton            matlab.ui.control.Button
        ResultPanel                  matlab.ui.container.Panel
        gainSwitch                   matlab.ui.control.Switch
        gain                         matlab.ui.control.UIAxes
        freq_map                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nd
        El
        MP
        fMP
        FLD
        OHC
        R
        freq_index
        loc_index
        stim % [1x2] bool array with 1: passive, 2: active
    end
    
    methods (Access = private)
        
        function plot_freq_map(app)
            MP = app.MP;
            FLD = app.FLD;
            hold(app.freq_map,"off");

            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);    
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    % traveling wave peaks
                    [~,peak_idx] = max(abs(R.yBM(:,f_idx)),[],1);
        
                    % frequency location map
                    
                    xx = MP.xx(peak_idx);
                    semilogy(app.freq_map,xx,freq,'-k','Marker','.','LineWidth',ii*0.6);
                    hold(app.freq_map,"on");
                end
            end

            semilogy(app.freq_map,MP.xx,loc2freq(MP.xx),'-r','linewidth',1.0);
            title(app.freq_map,'Frequency-location rel.');
            xlabel(app.freq_map,'X [mm]','Fontname','Arial','Fontsize',12);
            ylabel(app.freq_map,'Frequency [kHz]','Fontname','Arial','Fontsize',12);
            set(app.freq_map,'xtick',0:2:12,'xticklabel',{'0','','4','','8','','12'},'xlim',[min(MP.xx),max(MP.xx)]);
            set(app.freq_map,'ylim',[0.2,60],'ytick',[0.3, 1, 3, 10, 30],'ylim',[0.3 30],'xlim',[0 12]);
            grid(app.freq_map,'on');

        end
        
        function plot_gain(app,ind)

            MP = app.MP;
            FLD = app.FLD;
            if ~strcmp(app.gainSwitch.Value,'Hold')
                hold(app.gain,'off');                           
            end

            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    % traveling wave peaks
                    [~,peak_idx] = max(abs(R.yBM(:,f_idx)),[],1);
                    % approximated stapes displacement at each frequency
                    y = 150; x = [0,10];
                    [xx,yy] = meshgrid(x,y);
                    idx = rangesearch(FLD.Nd.x,[5,150],50);
                    d_stapes = zeros(length(f_idx),1);
                    for fi = 1:length(f_idx)
                        int_pre = scatteredInterpolant(FLD.Nd.x(idx{1},:), R.pre(idx{1},f_idx(fi)));
                        pp = int_pre(xx,yy);
                        dp = (pp(2)-pp(1))/(xx(2)-xx(1));
                        ww = 2*pi*freq(fi);
                        d_stapes(fi,1) = -dp(1)/((1j*ww)^2)/MP.rho*(1e3); % [nm]
                    end
        
                    % traveling wave
                    xx = MP.xx(peak_idx);
                    loc = [2,6,10];
                    if ~exist('ind','var')
                        poi_idx = knnsearch(xx.',loc.');
                    else
                        poi_idx = f_idx(ind);
                    end
                    nn = length(unique(poi_idx));
                    cmap = lines(3);
                    
                    for i = 1:nn
                        yy1 = abs(R.yBM(:,f_idx(poi_idx(i)))/d_stapes(poi_idx(i)));
                        yy2 = abs(R.yTM(:,f_idx(poi_idx(i)))/d_stapes(poi_idx(i)));
        
                        plot(app.gain,MP.xx,20*log10(yy1),'Color',cmap(1,:),'LineWidth',ii*0.6);
                        hold(app.gain,'on');
                        plot(app.gain,MP.xx,20*log10(yy2),'Color',cmap(2,:),'LineWidth',ii*0.6);
                        if isfield(R,'aDC')
                            yy3 = abs(R.aDC(:,f_idx(poi_idx(i)))/d_stapes(poi_idx(i)));
                            plot(app.gain,MP.xx,20*log10(yy3),'Color',cmap(3,:),'LineWidth',ii*0.6);
                        end
                    end
                end
            end
            title(app.gain,'Traveling wave');
            xlabel(app.gain,'X [mm]','Fontname','Arial','Fontsize',12);
            ylabel(app.gain,'Disp. re. stapes [dB]','Fontname','Arial','Fontsize',12);
            set(app.gain,'ylim',[-40 80],'xlim',[0 12]);
            grid(app.gain,'on');
            if isfield(app.R(1),'aDC')
                legend(app.gain,'yBM','yTM','aDC');
            else
                legend(app.gain,'yBM','yTM');
            end

        end
        
        function plot_cross_section(app)
          
            MP = app.MP;
            Nd = app.Nd;
            El = app.El;
            FLD = app.FLD;

            if app.stim(1)
                R = app.R(1);
            else
                R = app.R(2);
%                 R.Uf = app.R(2).Uf - app.R(1).Uf;
            end           

            za = 10; 
            zb = 2;

            if abs(MP.loc-za)<=2,
                dHB = 10;
            elseif abs(MP.loc-zb)<=2,
                dHB = 5;
            else
                dHB = 7;
            end

            loc = app.loc_index;
            nf = app.freq_index;
            locz = (MP.xx(loc)-MP.loc)*1e3; % [um]

            nT = find(strncmp(Nd.name,'E',1));
            Nd.Y(nT) = Nd.Y(nT) + dHB;
            nn = ((1:Nd.Nc)-1)*Nd.Nr + loc;
            nF = nn(~strcmpi(Nd.name(nn),'FF') & ~strcmpi(Nd.name(nn),'PP'));
%             nF = (Nd.Z<0.4*MP.dZ + locz & Nd.Z>=-0.5*MP.dZ + locz & ~strcmpi(Nd.name,'FF') & ~strcmpi(Nd.name,'PP'));    % find nodes at locz except the pseudo-node 

            if app.ScalingfactorEditField.Value > 0
                pfact = app.ScalingfactorEditField.Value;
            else
                pfact = 10;
                app.ScalingfactorEditField.Value = pfact;
            end
            ps = pfact/max([abs(1e-3*(R.yRL(loc,nf))),abs(1e-3*(R.yBM(loc,nf)))]);
            
            ndof = 6;
            fdir = 2;
            udof = MP.dof(2).udof;
            uu_f = R.Uf(udof,nf);
            idx = transpose(reshape(transpose((1:Nd.tdof)),ndof,Nd.N));
            yyr = uu_f(idx(:,1));
            yyt = uu_f(idx(:,2));

            [G,FLD] = make_AMatrix_FSI_2([],Nd,El,FLD,MP);

            if MP.opt_cfld == 1
%                 in = size(CFLD.nBot,2);; % code will need editing for more than one node per surface
                cc = R.Uf(MP.dof(6).cdof,nf);
                G = make_AMatrix_FSI_CFLD(Nd,FLD,G,MP);
                CFLD = FLD.CFLD;
                F_Corti = zeros(Nd.tdof,1);
                F_Corti(Nd.mapR2F) = G.Auc*cc(CFLD.BC,1);
                krow = (CFLD.nBot-1)*ndof + fdir; % column index
                F_Corti_bot = F_Corti(krow);
                krow = (CFLD.nTop-1)*ndof + fdir; % column index
                F_Corti_top = F_Corti(krow);

                perim = CFLD.Nd.perim(loc,:);
                cc = reshape(cc,4,CFLD.Nd.N);
                [~,idx] = sort(CFLD.Nd.x(CFLD.Nd.ind_Radi,1),'ascend');
                cp = cc(3,CFLD.Nd.ind_Radi(idx(loc)));
                p_map = jet(100);
            end
            F_scala = zeros(Nd.tdof,1);
            F_scala(Nd.mapR2F) = G.Aup*R.pre(FLD.BC,nf);
            krow = (FLD.nBot-1)*ndof + fdir;
            F_scala_bot = F_scala(krow);
            krow = (FLD.nTop-1)*ndof + fdir;
            F_scala_top = F_scala(krow);

            if (app.stim(2))
                [nd1_f, nd2_f, nd1OHC, nd2OHC] = eval_fOHC(app,nf,loc);
                [nd1_o, nd2_o, nd1OHB, nd2OHB] = eval_fMET(app,nf,loc);
            end

            max_F = max(abs(([F_scala_top(loc,:),F_scala_bot(loc,:)])));
            if MP.opt_cfld == 1
                max_F = max(abs([max_F,F_Corti_top(loc),F_Corti_bot(loc)]));
            end
            icount = 0;
            qq = 0;
            if strcmp(app.showMovie.Value,'Animate')
                dq = pi/5;
            else
                dq = 2*pi;
            end
            ct = clock;
            file_name = sprintf('./temp/cross_section_%2.0d%2.0d_%s.gif',ct(4),ct(5),R.stim);
            while true
               
                qq = qq + dq;
                figure(101); clf; ax1 = gca; 
                title(['Max. fluid force: ',num2str(max_F*1e-3,'%.3f'),'nN']);
                hold on

                % original structure
                for ie=1:El.N        
                    nds = [El.Nd1(ie), El.Nd2(ie)];       
%                     if abs(mean(Nd.Z(nds))-locz)<9 && ~strcmpi(El.name(ie),'ANK') && ~strcmpi(El.name(ie),'xHB') && ~strcmpi(El.name(ie),'DCr') && ~strcmpi(El.name(ie),'DCpz'),       
                    if ismember(nds,nF)    
                        plot(ax1,Nd.X(nds), Nd.Y(nds), 'Color',[0.8 0.8 0.8],'LineWidth', 2.0);                       
                    end
                end

                if ~exist('cyc','var')
                    cyc = 0:pi/10:2*pi;
                    for ii = 1:length(cyc)                    
                        xx = Nd.X(:) + real(yyr*exp(1j*cyc(ii)))*ps;
                        yy = Nd.Y(:) + real(yyt*exp(1j*cyc(ii)))*ps;
                        x_cyc(:,ii) = xx(nF); y_cyc(:,ii) = yy(nF);              
                    end
                end

                plot(ax1,x_cyc',y_cyc','Color',[0.8 0.8 0.8],'LineWidth',1);

                xx = Nd.X(:) + real(yyr*exp(1j*qq))*ps;
                yy = Nd.Y(:) + real(yyt*exp(1j*qq))*ps;

                p_ref = 5*((20e-6)*10^(MP.Pstim/20));

                if MP.opt_cfld == 1                    
%                     patch(xx(perim),yy(perim),...
%                         p_map(round(interp1([-1,1],[1,100],real(cp*exp(1j*qq))/abs(cp))),:),...
%                         'LineStyle','none','FaceAlpha',0.75);
                    patch_c_tag = round(interp1([-p_ref,p_ref],[1,100],real(cp*exp(1j*qq)),'linear','extrap'));
                    if patch_c_tag > 100
                        patch_c_tag = 100;
                    elseif patch_c_tag < 1
                        patch_c_tag = 1;
                    end
                    patch(ax1,xx(perim),yy(perim),...
                        p_map(patch_c_tag,:),...
                        'LineStyle','none','FaceAlpha',0.75);
                end
                
                uu_f = reshape(uu_f,ndof,Nd.N);
                for ie=1:El.N
                    nds = [El.Nd1(ie), El.Nd2(ie)];       
%                     if abs(mean(Nd.Z(nds))-locz)<9 && ~strcmpi(El.name(ie),'ANK') && ~strcmpi(El.name(ie),'xHB') && ~strcmpi(El.name(ie),'DCr') && ~strcmpi(El.name(ie),'DCpz'),      
                    if ismember(nds,nF)  
                        if El.type(ie) < 1
                            [R,T] = rotation_matrix(app,ie);
                            d = uu_f(:,nds);
                            coord = [Nd.X(nds);Nd.Y(nds)];
                            d = R*real(d(:)*exp(1j*qq)); % move displacement values to local frame
                            L = El.L(ie);
                            nl = 20;
                            x = linspace(0,El.L(ie),nl);
                            xx_l = zeros(2,nl);
                            dy_l = zeros(1,nl);
                            dx_l = zeros(1,nl);
                            O = zeros(1,nl);
                            for ii = 1:numel(x)
                                % this is in x-y plane
                                N = [(L-x(ii))/L, x(ii)/L]; %[u1,u2]
                                xx_l(:,ii) = coord*N.';
                                dx_l(ii) = (N*d([1;7]));
                                N = [1-3*x(ii)^2/L^2+2*x(ii)^3/L^3, x(ii)-2*x(ii)^2/L+x(ii)^3/L^2,...
                                     3*x(ii)^2/L^2-2*x(ii)^3/L^3,-x(ii)^2/L+x(ii)^3/L^2]; %[v1,t1,v2,t2] local
                                dy_l(ii) = (N*d([2;6;8;12]));
                            end
                            dd = T.'*ps*[dx_l;dy_l;O];
                            plot(ax1,xx_l(1,:) + dd(1,:), xx_l(2,:) + dd(2,:), 'Color',[0 0 0],'LineWidth', 2.0);
                        else
                            plot(ax1,xx(nds), yy(nds), 'Color',[0 0 0],'LineWidth', 2.0); 
                        end
                    end
                end
                
                plot(ax1,xx(nF), yy(nF), 'o','Color','none','MarkerFaceCol',[0, 0.4470, 0.7410]);
                
                if strcmp(app.showForce.Value,'Forces')
                    % forces
                    ffact = 5*pfact;
                    x_q = [xx(FLD.nTop(loc,:));xx(FLD.nBot(loc,:))];
                    y_q = [yy(FLD.nTop(loc,:));yy(FLD.nBot(loc,:))];
                    u_q = zeros(size(x_q))*ffact/max_F;
                    v_q = [real(F_scala_top(loc,:)*exp(1j*qq)),real(F_scala_bot(loc,:)*exp(1j*qq))]*ffact/max_F;
                    quiver(x_q(:),y_q(:),u_q(:),v_q(:),'Color','b','LineWidth',2,'Autoscale','off');
    
                    if MP.opt_cfld == 1
                        x_q = [xx(CFLD.nTop(loc,:));xx(CFLD.nBot(loc,:))];
                        y_q = [yy(CFLD.nTop(loc,:));yy(CFLD.nBot(loc,:))];
                        u_q = zeros(size(x_q))*ffact/max_F;
                        v_q = [real([F_Corti_top(loc,:),F_Corti_bot(loc,:)]*exp(1j*qq))]*ffact/max_F;
                        quiver(ax1,x_q(:),y_q(:),u_q(:),v_q(:),'Color','c','LineWidth',2,'Autoscale','off');
                    end
     
                    if (app.stim(2))
                        x_q = xx([nd1OHC, nd2OHC]).';
                        y_q = yy([nd1OHC, nd2OHC]).';
                        u_q = [real([nd1_f(1),nd2_f(1)]*exp(1j*qq))]*ffact/max_F;
                        v_q = [real([nd1_f(2),nd2_f(2)]*exp(1j*qq))]*ffact/max_F;
                        quiver(ax1,x_q,y_q,u_q,v_q,'Color','r','LineWidth',2,'Autoscale','off');
    
                        x_q = xx([nd1OHB, nd2OHB]).';
                        y_q = yy([nd1OHB, nd2OHB]).';
                        u_q = [real([nd1_o(1),nd2_o(1)]*exp(1j*qq))]*ffact/max_F;
                        v_q = [real([nd1_o(2),nd2_o(2)]*exp(1j*qq))]*ffact/max_F;
                        quiver(ax1,x_q,y_q,u_q,v_q,'Color','y','LineWidth',2,'Autoscale','off');                    
                    end
                end

                xlim([-100, 300]); % should be adjusted manually for now
                ylim([-50 150]); % should be adjusted manually for now
                daspect(ax1,[1 1 1]);
                hold off;
                if qq < 2*pi
                    app.Prompt_message.Text = 'Recording frames ...';            
                    exportgraphics(ax1, file_name, ...
                        "Append",true,'Resolution',300);
                    icount = icount + 1;
%                     frames(icount) = getframe(gcf);
                else
                    app.Prompt_message.Text = '';
                    break;
                end
            end
%             v = VideoWriter('./temp/cross_section','Motion JPEG AVI');
%             v.FrameRate = 30; v.Quality = 100; open(v);
%             writeVideo(v,frames); close(v);
        end
        
        function plot_scala_fluid(app)

            MP = app.MP;
            Nd = app.Nd;
            El = app.El;
            FLD = app.FLD;
            CFLD = FLD.CFLD;

            if app.stim(1)
                R = app.R(1);
            else
                R = app.R(2);
            end

            nf = app.freq_index;

            ndof = 6;
            fdir = 2;
            udof = MP.dof(2).udof;
            uu_f = R.Uf(udof,nf);
            idx = transpose(reshape(transpose((1:Nd.tdof)),ndof,Nd.N));
            yyr = uu_f(idx(:,1));
            yyt = uu_f(idx(:,2));

            [G,FLD] = make_AMatrix_FSI_2([],Nd,El,FLD,MP);

            bcTM = FLD.indTM;
            [~,idx] = sort(FLD.Nd.x(bcTM,1),'ascend');
            bcTM = bcTM(idx);
            bcBM = FLD.indBM;
            [~,idx] = sort(FLD.Nd.x(bcBM,1),'ascend');
            bcBM = bcBM(idx);

            pre = R.pre(:,nf);           
            F_scala = zeros(Nd.tdof,1);
            F_scala(Nd.mapR2F) = G.Aup*pre(FLD.BC,1);
            krow = (FLD.nBot-1)*ndof + fdir;
            F_scala_bot = F_scala(krow);
            krow = (FLD.nTop-1)*ndof + fdir;
            F_scala_top = F_scala(krow);

            max_F = max(abs(([F_scala_top(:);F_scala_bot(:)])));

            if MP.opt_cfld == 1
                in = 1; % code will need editing for more than one node per surface
                cc = R.Uf(MP.dof(6).cdof,nf);
                cc = reshape(cc,4,CFLD.Nd.N);
                cpre = cc(3,:);
            end
            figure(102), clf,
            set(gcf,'pos',[50,200,1600,100]);
            set(gcf,'Color','w');

            pa = [0.1, 0.2, 0.8, 0.9];
            
            subplot('position', pa);
            hold on;

            icount = 0;
            qq = 0;
            dq = pi/30;
            max_P = max(abs(pre));
            ct = clock;
            file_name = sprintf('./temp/scala_fluid_%2.0d%2.0d.gif',ct(4),ct(5));
            while true
                
                if ~ishandle(102)
                    break
                end

                icount = icount + 1;
                qq = qq + dq;
                figure(102); clf; ax = gca;
                hold on;
                sp = real(pre*exp(1i*qq));
                patch('Faces',FLD.El.node,'Vertices',FLD.Nd.x,...
                    'FaceVertexCData',sp,'FaceColor','interp',...
                    'LineStyle','none');
                cp = real(cpre(:)*exp(1i*qq));
                patch('Faces',CFLD.El.node(:,[1:4]),'Vertices',CFLD.Nd.x,...
                    'FaceVertexCData',cp,'FaceColor','interp',...
                    'LineStyle','none');
                patch('Faces',CFLD.El.node(:,[1:4]),'Vertices',[CFLD.Nd.x(:,1),-CFLD.Nd.x(:,2)],...
                    'FaceVertexCData',cp,'FaceColor','interp',...
                    'LineStyle','none');  
                colormap jet;
                colorbar;
                caxis([-max_P max_P]);
                x_q = FLD.Nd.x(bcTM,1);
                y_q = FLD.Nd.x(bcTM,2);
                u_q = zeros(size(x_q));
                v_q = real(F_scala_top*exp(1i*qq))*100/max_F;                
                plot(x_q, y_q + v_q, 'r-', 'LineWidth', 0.5)
%                 quiver(x_q,y_q,u_q,v_q,'Color','r','LineWidth',1,'AutoScaleFactor',0.3);
% 
                x_q = FLD.Nd.x(bcBM,1);
                y_q = FLD.Nd.x(bcBM,2);
                u_q = zeros(size(x_q));
                v_q = real(F_scala_bot*exp(1i*qq))*100/max_F;
                plot(x_q, y_q + v_q, 'k-', 'LineWidth', 0.5)
%                 quiver(x_q,y_q,u_q,v_q,'Color','k','LineWidth',1,'AutoScaleFactor',0.3);

                xlim([-200, 12200]); % should be adjusted manually for now
                ylim([-0.5*FLD.H 0.5*FLD.H]); % should be adjusted manually for now
                hold off;
                if qq < 2*pi
                    app.Prompt_message.Text = 'Recording frames ...';
                    exportgraphics(ax, file_name, ...
                        "Append",true,'Resolution',300);
                else
                    app.Prompt_message.Text = '';
                    break;
                end
            end
        end
        
        function [nd1_f, nd2_f, nd1OHC, nd2OHC] = eval_fOHC(app,nf,loc)

            fMP = app.fMP;
            MP = app.MP;
            Nd = app.Nd;
            El = app.El;
            OHC = app.OHC;            
            ndof = 6;
            idx = reshape(transpose((1:Nd.tdof)),ndof,Nd.N);
            nOHC = find(strcmpi('OHC',El.name));            
            nd1OHC = El.Nd1(nOHC(loc));
            nd2OHC = El.Nd2(nOHC(loc));

            edof = MP.dof(3).edof;
            ee = app.R(2).Uf(edof,nf);
            [Aue, ~] = make_Aue(MP,Nd,El,OHC);
            f = zeros(MP.dof(2).n,1);
            f(Nd.BC,1) = -Aue*ee;
            nd1_f = f([1;2] + (nd1OHC-1)*ndof);
            nd2_f = f([1;2] + (nd2OHC-1)*ndof);
        end
        
        function [nd1_f, nd2_f, nd1OHB, nd2OHB] = eval_fMET(app,nf,loc)

            fMP = app.fMP;
            MP = app.MP;
            Nd = app.Nd;
            El = app.El;
            OHC = app.OHC;
            HB = OHC.HB;
            ndof = 6;
            idx = reshape(transpose((1:Nd.tdof)),ndof,Nd.N);
            nOHB = find(strcmpi('OHB',El.name));
            nd1OHB = El.Nd1(nOHB(loc));
            nd2OHB = El.Nd2(nOHB(loc));

            odof = MP.dof(5).odof;
            oo = app.R(2).Uf(odof,nf);

            [Auo, ~] = make_Auo(MP,Nd,El,OHC,HB);

            f = zeros(MP.dof(2).n,1);
            f(Nd.BC,1) = -Auo*oo;
            nd1_f = f([1;2] + (nd1OHB-1)*ndof);
            nd2_f = f([1;2] + (nd2OHB-1)*ndof);
            
        end
        
        function plot_traveling_wave(app,ind)

            MP = app.MP;
            figure(103); clf;
            set(gcf,"Position",[250, 550, 1500, 400]);
            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    % traveling wave
                    cmap = lines(2);
                    ax = subplot(1,4,1);
                    yy1 = R.yBM(:,f_idx(ind));
                    yy2 = R.yRL(:,f_idx(ind));       
                    h1 = plot(ax,MP.xx,real(yy1),'Color',cmap(1,:),'LineWidth',ii*0.6);
                    hold(ax,'on');
                    plot(ax,MP.xx,abs(yy1),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','--');
                    plot(ax,MP.xx,-abs(yy1),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','--');                    
                    h2 = plot(ax,MP.xx,real(yy2),'Color',cmap(2,:),'LineWidth',ii*0.6);
                    plot(ax,MP.xx,abs(yy2),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','--');
                    plot(ax,MP.xx,-abs(yy2),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','--');
                    xlim([0 12]);
                    title(ax,'Traveling wave');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Disp. [nm]','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    grid(ax,'on');
                    legend([h1,h2],'yBM','yRL');

                    ax = subplot(1,4,2);
                    ref = (20e-6)*10^(MP.Pstim/20);
                    semilogy(ax,MP.xx,abs(yy1)/ref,'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','-');
                    hold(ax,'on');
                    semilogy(ax,MP.xx,abs(yy2)/ref,'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','-');
                    ylim([3e-1, 3e3]);
                    xlim([0 12]);
                    title(ax,'Traveling wave');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Disp. [nm/Pa]','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    grid(ax,'on');

                    ax = subplot(1,4,3);
                    plot(ax,MP.xx,unwrap(angle(yy1)/pi*180),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','-');
                    hold(ax,'on');
                    plot(ax,MP.xx,unwrap(angle(yy2)/pi*180),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','-');
                    xlim([0 12]);
                    title(ax,'Traveling wave');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Deg.','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    grid(ax,'on');
                    ax = subplot(1,4,4);
                    plot(ax,MP.xx,unwrap(angle(yy2./yy1)/pi*180),'Color','k','LineWidth',ii*0.6,'LineStyle','-');
                    hold(ax,'on');
                    xlim([0 12]);
                    title(ax,'Traveling wave');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Deg.','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    grid(ax,'on');

                end
            end         
        end
        
        function plot_xHB_po(app,ind)

            MP = app.MP;
            OHC = app.OHC;
            HB = [OHC.HB];
            fig = figure(104); clf;
            set(fig,'Position',[600,500,1000,400]);
            ax = gca;

            R = app.R(2);
            % non-zero frequency bins
            f_idx = find(R.freq ~= 0);
            freq = R.freq(f_idx);
            % traveling wave
            odof = MP.dof(5).odof;
            po = R.Uf(odof,f_idx(ind));
            yy1 = R.xHB(:,f_idx(ind)); 

            yyaxis(ax,"left");
            h2 = plot(ax,MP.xx,real(po),'Color','k','LineWidth',1.2);
            hold(ax,'on');
            plot(ax,MP.xx,abs(po),'Color','k','LineWidth',1.2,'LineStyle','--');
            plot(ax,MP.xx,-abs(po),'Color','k','LineWidth',1.2,'LineStyle','--');
            ax.YAxis(1).Color = 'k';

            yyaxis(ax,"right");
            h1 = plot(ax,MP.xx,real(yy1),'Color','r','LineWidth',0.6);
            hold(ax,'on');            
            plot(ax,MP.xx,abs(yy1),'Color','r','LineWidth',0.6,'LineStyle','--');
            plot(ax,MP.xx,-abs(yy1),'Color','r','LineWidth',0.6,'LineStyle','--');
            ylabel(ax,'Disp. [nm]','Fontname','Arial','Fontsize',12);
            ax.YAxis(2).Color = 'r';

            title(ax,'MET response');
            xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
            
            set(ax,'xlim',[0 12]);
            grid(ax,'on');
            legend([h1,h2],'xHB [nm]','po');

            xx = 1e-3*linspace(-20,20,21); % input to channel_2state function is in [um]
            xa = zeros(size(xx));
            idx = knnsearch(MP.xx(:),freq2loc(freq(ind)));
            yy = channel_2state(HB(idx), xx, xa);
            ax2 = axes(fig);
            set(ax2,'Position',[0.1510,0.6275,0.1550,0.2650]);
            plot(ax2,1e3*xx,yy,'k','LineWidth',1);
            set(ax2,'LineWidth',1,'TickDir','in','xlim',[-20,20],'xtick',...
                [-10,0,10],'ylim',[0,1],'ytick',linspace(0,1,5),'yticklabel',...
                {'0','','0.5','','1'});
            xlabel(ax2,'xHB [nm]','FontSize',12,'BackgroundColor','w');
            set(ax2, 'TickLength', [0.04, 0.04]);
            grid(ax2,'on');

            % traveling wave peaks
            hold(ax2,'on');
            [xx,peak_idx] = max(abs(yy1),[],1);
            yy = abs(po(peak_idx)) + HB(peak_idx).po0;
            plot(ax2,xx,yy,'r','Marker','.','MarkerSize',24);
        end
        
        function plot_area_change(app,ind)
            MP = app.MP;
            Nd = app.Nd;
            FLD = app.FLD;

            figure(105); clf;
            ax = gca;
            
            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    dA = compute_area_change(MP, Nd, FLD, R, ind);
                    dr = 1e3*dA(:)./(2*pi*FLD.CFLD.Nd.radi(:));
                    yy1 = dr;
                    % traveling wave
                    cmap = lines(2);
                        h(ii) = plot(ax,MP.xx,real(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6);
                        hold(ax,'on');
                        plot(ax,MP.xx,abs(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6,'LineStyle','--');
                        plot(ax,MP.xx,-abs(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6,'LineStyle','--');                    
                end
            end
            title(ax,'Radius Change');
            xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
            ylabel(ax,'dr [nm]','Fontname','Arial','Fontsize',12);
            set(ax,'xlim',[0 12]);
            grid(ax,'on');
            
            if length(h) > 1
                legend([h(1),h(2)],'Passive','Active');
            end
        end
        
        function plot_permeation(app,ind)
            MP = app.MP;
            FLD = app.FLD;

            figure(106); clf;
            ax = gca;
            
            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    vp = compute_permeation(MP, FLD, R, ind);
                    yy1 = vp;
                    % traveling wave
                    cmap = lines(2);
                        h(ii) = plot(ax,MP.xx,real(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6);
                        hold(ax,'on');
                        plot(ax,MP.xx,abs(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6,'LineStyle','--');
                        plot(ax,MP.xx,-abs(yy1),'Color',cmap(ii,:),'LineWidth',ii*0.6,'LineStyle','--');                    
                end
            end
            title(ax,'Permeation');
            xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
            ylabel(ax,'v_p [um/ms]','Fontname','Arial','Fontsize',12);
            set(ax,'xlim',[0 12]);
            grid(ax,'on');
            
            if length(h) > 1
                legend([h(1),h(2)],'Passive','Active');
            end            
        end
        
        function plot_corti(app,ind)

            CFLD = app.FLD.CFLD;
            Nd = CFLD.Nd;

            cdof = MP.dof(6).cdof;
            cc = reshape(R.Uf(cdof,ind),4,Nd.N);
            figure(106); clf;
            
            
        end
        
        function plot_amp_tuning(app)

            MP = app.MP;
            R = app.R;
            % passive
            % traveling wave peaks
            [max_yBM_psv,peak_idx] = max(abs(R(1).yBM),[],1);
        
            % active
            % traveling wave peaks
            [max_yBM_act,peak_idx] = max(abs(R(2).yBM),[],1);
            denom = zeros(size(max_yBM_act));
            for ii = 1:length(peak_idx)
                denom(ii) = abs(R(1).yBM(peak_idx(ii),ii));
            end
            % frequency map
            xx_pBM_act = MP.xx(peak_idx);           
            % amplification
            amp = 20*log10(max_yBM_act./denom);
       
            % tuning - Q10dB
            q = zeros(size(R(1).freq));
            for fi = 1:length(R(1).freq)
                yy = abs(20*log10(abs(R(2).yBM(:,fi))./max_yBM_act(fi))+10);
                [~,i_cut] = min(yy(peak_idx(fi):end));
                x1 = MP.xx(peak_idx(fi) + i_cut - 1);
                [~,i_cut] = min(yy(1:peak_idx(fi)));
                x2 = MP.xx(i_cut);
                ff = exp(feval(fit(xx_pBM_act(:),log(R(1).freq(:)),'poly1'),[x1,x2]));
                q(fi) = R(1).freq(fi)/abs(ff(1)-ff(2));
            end
       
            figure(105); clf;
            set(gcf,'Position',[20,500,350,450]);
            subplot(2,1,1);
            plot(xx_pBM_act,amp,'k','LineWidth',1,Marker='.');
            set(gca,'XLim',[0, 12],'XTick',linspace(0,12,7),'XTickLabel',[]);
            ylabel('Amplification [dB]','Fontname','Arial','Fontsize',12);
            grid on;
            subplot(2,1,2);
            plot(xx_pBM_act,q,'k','LineWidth',1,Marker='.');
            set(gca,'XLim',[0, 12],'XTick',linspace(0,12,7));
            xlabel('X [mm]','Fontname','Arial','Fontsize',12); 
            ylabel('Q10dB','Fontname','Arial','Fontsize',12);
            grid on;
            drawnow                        
        end
        
        function  [R,T] = rotation_matrix(app,ie)
            El = app.El;
            if abs(El.dir(ie,3)-1)<0.1,
                ez = [1, 0, 0];
                % note that most elements alligned either radial(x) direction
                % or longitudianl(z) direction
            else
                ez = [0, 0, 1];
            end
        
            xv = El.dir(ie,:);
        
            yv = mycross(ez,xv);
            yv = yv/norm(yv);
            zv = mycross(xv,yv);
            zv = zv/norm(zv);
        
            T = [xv;yv;zv];
        % %     R = zeros(9);
        % %     for ii=1:4,
        % %         R((ii-1)*3+1:(ii-1)*3+3,(ii-1)*3+1:(ii-1)*3+3) = T;
        % %     end
            O33 = zeros(3);    
            R = [T, O33, O33, O33;  O33, T, O33, O33;  O33, O33, T, O33;   O33, O33, O33, T;];
        end
        
        function plot_fluid_pressure(app,ind)
            MP = app.MP;
            FLD = app.FLD;
            figure(106); clf;
            set(gcf,"Position",[250, 550, 1500, 400]);

            pdof = MP.dof(1).pdof;
            if MP.opt_cfld == 1
                cdof = reshape(MP.dof(6).cdof,4,FLD.CFLD.Nd.N);
            end
            for ii = 1:length(app.stim)
                if app.stim(ii)
                    R = app.R(ii);
                    % non-zero frequency bins
                    f_idx = find(R.freq ~= 0);
                    freq = R.freq(f_idx);
                    % traveling wave
                    n_color = 2;
                    ax = subplot(1,3,1);
                    sp = R.Uf(pdof,f_idx(ind));
                    cp = R.Uf(cdof(3,:),f_idx(ind));
                    bcTM = FLD.indTM;
                    [~,idx] = sort(FLD.Nd.x(bcTM,1),'ascend');
                    bcTM = bcTM(idx);
                    SV_p = sp(bcTM);
                    bcBM = FLD.indBM;
                    [~,idx] = sort(FLD.Nd.x(bcBM,1),'ascend');
                    bcBM = bcBM(idx);
                    ST_p = sp(bcBM);
                    if MP.opt_cfld == 1
                        n_color = 3;
                        CF_p = cp(FLD.CFLD.Nd.ind_Radi);
                    end   
                    cmap = lines(n_color);
                    h1 = plot(ax,MP.xx,real(SV_p),'Color',cmap(1,:),'LineWidth',ii*0.6);
                    hold(ax,'on');
                    plot(ax,MP.xx,abs(SV_p),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','--');
                    plot(ax,MP.xx,-abs(SV_p),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','--');                    
                    h2 = plot(ax,MP.xx,real(ST_p),'Color',cmap(2,:),'LineWidth',ii*0.6);
                    plot(ax,MP.xx,abs(ST_p),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','--');
                    plot(ax,MP.xx,-abs(ST_p),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','--');
                    if MP.opt_cfld == 1
                        h3 = plot(ax,MP.xx,real(CF_p),'Color',cmap(3,:),'LineWidth',ii*0.6);
                        plot(ax,MP.xx,abs(CF_p),'Color',cmap(3,:),'LineWidth',ii*0.6,'LineStyle','--');
                        plot(ax,MP.xx,-abs(CF_p),'Color',cmap(3,:),'LineWidth',ii*0.6,'LineStyle','--');
                    end
                    xlim([0 12]);
                    title(ax,'Traveling wave');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Pressure [Pa]','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    grid(ax,'on');
                    if MP.opt_cfld == 1
                        hh = [h1,h2,h3];
                        names = {'SV','ST','CF'};
                    else
                        hh = [h1,h2];
                        names = {'SV','ST'};
                    end
                    legend(ax,hh,names);

                    ax = subplot(1,3,2);
                    h1 = plot(ax,MP.xx,unwrap(angle(SV_p)/pi*180),'Color',cmap(1,:),'LineWidth',ii*0.6,'LineStyle','-');
                    hold(ax,'on');
                    h2 = plot(ax,MP.xx,unwrap(angle(ST_p)/pi*180),'Color',cmap(2,:),'LineWidth',ii*0.6,'LineStyle','-');
                    h3 = plot(ax,MP.xx,unwrap(angle(ST_p./SV_p)/pi*180),'Color','k','LineWidth',ii*0.6,'LineStyle','-');
                    title(ax,'Phase');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Deg.','Fontname','Arial','Fontsize',12);
                    set(ax,'xlim',[0 12]);
                    legend(ax,[h1,h2,h3],{'SV','ST','SV/ST'});
                    grid(ax,'on');
                    ax = subplot(1,3,3);
                    title(ax,'BM pressure difference');
                    h1 = plot(ax,MP.xx,real(SV_p-ST_p),'Color','k','LineWidth',ii*0.6,'LineStyle','-');
                    hold(ax,'on');
                    plot(ax,MP.xx,abs(SV_p-ST_p),'Color','k','LineWidth',ii*0.6,'LineStyle','--');
                    plot(ax,MP.xx,-abs(SV_p-ST_p),'Color','k','LineWidth',ii*0.6,'LineStyle','--');
                    xlabel(ax,'X [mm]','Fontname','Arial','Fontsize',12);
                    ylabel(ax,'Pressure [Pa]','Fontname','Arial','Fontsize',12);
                    legend(ax,h1,'SV-ST');
                end
            end                     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Nd, El, MP, fMP, FLD, OHC, R)
            % assign input values to app
            app.Nd = Nd;
            app.El = El;
            app.MP = MP;
            app.fMP = fMP;
            app.FLD = FLD;
            app.OHC = OHC;
            app.R = R;

            % rename panel
            app.ResultPanel.Title = [' ',num2str(min(MP.freq),'%.1f'),'-',...
                num2str(max(MP.freq),'%.1f'),'kHz at ',...
                num2str(MP.Pstim,'%.1f'),'dB'];

            app.FreqkHzDropDown.Items = arrayfun(@num2str, R(1).freq, 'UniformOutput', 0);
            app.FreqkHzDropDown.Value = num2str(R(1).freq(round(0.5*length(R(1).freq))));

            app.PassiveButton.Value = 1;
            app.stim(1) = true;
            app.stim(2) = false;
            if length(R) == 2
                app.ActiveButton.Value = 1;
                app.stim(2) = true;
            end
            plot_freq_map(app);
            plot_gain(app);
        end

        % Value changed function: FreqkHzDropDown
        function FreqkHzDropDownValueChanged(app, event)
            value = app.FreqkHzDropDown.Value;
            R = app.R(1);
            app.freq_index = knnsearch(R.freq(:),str2double(value));
        end

        % Button pushed function: PlotOoCGainButton
        function PlotOoCGainButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_gain(app,app.freq_index);
        end

        % Button pushed function: DemocrosssectionButton
        function DemocrosssectionButtonPushed(app, event)
            MP = app.MP;
            if ~isempty(app.freq_index)
                if app.stim(1)
                    if app.stim(2)
                        warndlg('Animating passive response.For active response animation, deselect passive.');
                    end
                elseif ~app.stim(2)
                    warndlg('Select a stimulation type first.');
                    return;
                end
                app.Prompt_message.Text = 'Waiting for user to select a location ...';
                   
                roi = drawpoint(app.gain);
                app.Prompt_message.Text = '';
                xx = MP.xx;
                app.loc_index = knnsearch(xx(:),roi.Position(1));
                delete(roi);
                plot_cross_section(app);
            else
                warndlg('Select and plot a single frequency response first.');
            end
        end

        % Button pushed function: DemoscalafluidButton
        function DemoscalafluidButtonPushed(app, event)
            if ~isempty(app.freq_index)
                if app.stim(1)
                    if app.stim(2)
                        warndlg('Animating passive response.For active response animation, deselect passive.');
                    end
                elseif ~app.stim(2)
                    warndlg('Select a stimulation type first.');
                    return;
                end
                plot_scala_fluid(app)
            else
                warndlg('Select and plot a single frequency response first.');
            end
        end

        % Value changed function: PassiveButton
        function PassiveButtonValueChanged(app, event)
            value = app.PassiveButton.Value;
            app.stim(1) = value;
%             plot_freq_map(app);
            plot_gain(app);
        end

        % Value changed function: ActiveButton
        function ActiveButtonValueChanged(app, event)
            value = app.ActiveButton.Value;
            if length(app.R) == 2
                app.stim(2) = value;
            else
                if value
                warndlg('The result does not include active response.');
                end
            end
%             plot_freq_map(app);
            plot_gain(app);
        end

        % Button pushed function: PlotTravelingwaveButton
        function PlotTravelingwaveButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_traveling_wave(app,app.freq_index);
        end

        % Button pushed function: PlotxHBpoButton
        function PlotxHBpoButtonPushed(app, event)
            if ~(length(app.R) == 2)
                warndlg('The result does not include active response.');
                return;
            end
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_xHB_po(app,app.freq_index);
        end

        % Button pushed function: PlotAreaChangeButton
        function PlotAreaChangeButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_area_change(app,app.freq_index);
        end

        % Button pushed function: PlotPermeationButton
        function PlotPermeationButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_permeation(app,app.freq_index);            
        end

        % Button pushed function: PlotCortifluidButton
        function PlotCortifluidButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_corti(app,app.freq_index);               
        end

        % Button pushed function: PlotAmpTuningButton
        function PlotAmpTuningButtonPushed(app, event)
            if ~(length(app.R) == 2)
                warndlg('The result does not include active response.');
                return;
            end
            plot_amp_tuning(app);
        end

        % Button pushed function: PlotFluidPressureButton
        function PlotFluidPressureButtonPushed(app, event)
            R = app.R(1);
            if isempty(app.freq_index)
                app.freq_index = knnsearch(R.freq(:),str2double(app.FreqkHzDropDown.Value));
            end
            plot_fluid_pressure(app,app.freq_index);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.freq_map = uiaxes(app.ResultPanel);
            xlabel(app.freq_map, 'X')
            ylabel(app.freq_map, 'Y')
            zlabel(app.freq_map, 'Z')
            app.gain = uiaxes(app.ResultPanel);
            xlabel(app.gain, 'X')
            ylabel(app.gain, 'Y')
            zlabel(app.gain, 'Z')
            app.gainSwitch = uiswitch(app.ResultPanel, 'slider');
            app.gainSwitch.Items = {'', 'Hold'};
            app.gainSwitch.Value = '';
            app.PlotOoCGainButton = uibutton(app.UIFigure, 'push');
            app.PlotOoCGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOoCGainButtonPushed, true);
            app.PlotOoCGainButton.Text = 'Plot OoC Gain';
            app.FreqkHzDropDownLabel = uilabel(app.UIFigure);
            app.FreqkHzDropDownLabel.Text = 'Freq. [kHz]';
            app.FreqkHzDropDown = uidropdown(app.UIFigure);
            app.FreqkHzDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqkHzDropDownValueChanged, true);
            app.Prompt_message = uilabel(app.UIFigure);
            app.Prompt_message.Text = '';
            app.DemocrosssectionButton = uibutton(app.UIFigure, 'push');
            app.DemocrosssectionButton.ButtonPushedFcn = createCallbackFcn(app, @DemocrosssectionButtonPushed, true);
            app.DemocrosssectionButton.Text = 'Demo cross-section';
            app.ScalingfactorEditFieldLabel = uilabel(app.UIFigure);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.DemoscalafluidButton = uibutton(app.UIFigure, 'push');
            app.DemoscalafluidButton.ButtonPushedFcn = createCallbackFcn(app, @DemoscalafluidButtonPushed, true);
            app.DemoscalafluidButton.Text = 'Demo scala fluid';
            app.SpanEditFieldLabel = uilabel(app.UIFigure);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.PassiveButton = uibutton(app.UIFigure, 'state');
            app.PassiveButton.ValueChangedFcn = createCallbackFcn(app, @PassiveButtonValueChanged, true);
            app.PassiveButton.Text = 'Passive';
            app.ActiveButton = uibutton(app.UIFigure, 'state');
            app.ActiveButton.ValueChangedFcn = createCallbackFcn(app, @ActiveButtonValueChanged, true);
            app.ActiveButton.Text = 'Active';
            app.StimulationLabel = uilabel(app.UIFigure);
            app.StimulationLabel.Text = 'Stimulation';
            app.PlotTravelingwaveButton = uibutton(app.UIFigure, 'push');
            app.PlotTravelingwaveButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTravelingwaveButtonPushed, true);
            app.PlotTravelingwaveButton.Text = 'Plot Traveling wave';
            app.PlotxHBpoButton = uibutton(app.UIFigure, 'push');
            app.PlotxHBpoButton.ButtonPushedFcn = createCallbackFcn(app, @PlotxHBpoButtonPushed, true);
            app.PlotxHBpoButton.Text = 'Plot xHB-po';
            app.PlotAreaChangeButton = uibutton(app.UIFigure, 'push');
            app.PlotAreaChangeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAreaChangeButtonPushed, true);
            app.PlotAreaChangeButton.Text = 'Plot Area Change';
            app.PlotPermeationButton = uibutton(app.UIFigure, 'push');
            app.PlotPermeationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPermeationButtonPushed, true);
            app.PlotPermeationButton.Text = 'Plot Permeation';
            app.PlotCortifluidButton = uibutton(app.UIFigure, 'push');
            app.PlotCortifluidButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCortifluidButtonPushed, true);
            app.PlotCortifluidButton.Text = 'Plot Corti fluid';
            app.PlotAmpTuningButton = uibutton(app.UIFigure, 'push');
            app.PlotAmpTuningButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAmpTuningButtonPushed, true);
            app.PlotAmpTuningButton.Text = 'Plot Amp/Tuning';
            app.showForce = uiswitch(app.UIFigure, 'slider');
            app.showForce.Items = {'', 'Forces'};
            app.showForce.Value = '';
            app.showMovie = uiswitch(app.UIFigure, 'slider');
            app.showMovie.Items = {'', 'Animate'};
            app.showMovie.Value = '';
            app.PlotFluidPressureButton = uibutton(app.UIFigure, 'push');
            app.PlotFluidPressureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFluidPressureButtonPushed, true);
            app.PlotFluidPressureButton.Text = 'Plot Fluid Pressure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = post_NLFD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end